classdef StreamlinesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StreamlinesUIFigure    matlab.ui.Figure
        StreamlinesLabel       matlab.ui.control.Label
        HelpStreamlinesButton  matlab.ui.control.Button
        ApplyButton            matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        LoadStreamlinesButton  matlab.ui.control.Button
        NewStreamlinesButton   matlab.ui.control.Button
        StatusMessageLabel     matlab.ui.control.Label
        StatusLampLabel        matlab.ui.control.Label
        StatusLamp             matlab.ui.control.Lamp
        FileFieldLabel         matlab.ui.control.Label
        FileField              matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp              % Reference to the main app
        Geometry                % Struct that holds the geometry parameters        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Make a local copy of the geometry parameters
            app.Geometry    = app.CallingApp.Geometry;

            % Set the component values
            app.FileField.Value = app.Geometry.StreamlinesFile;
            
        end

        % Close request function: StreamlinesUIFigure
        function StreamlinesUIFigureCloseRequest(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Geometry.StreamlinesFile = 'StreamsRnd.mat';
            app.FileField.Value = app.Geometry.StreamlinesFile;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Assign the new geometry properties to the geometry
            % properties of the calling app
            app.CallingApp.Geometry = app.Geometry;
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: LoadStreamlinesButton
        function LoadStreamlinesButtonPushed(app, event)
            value = uigetfile([app.CallingApp.PATHS.GeometriesPath filesep app.Geometry.Folder]);
            
            % Show app window again after closing uigetfile window
            figure(app.CallingApp.MicrobubbleFlowSimulatorUIFigure)
            figure(app.StreamlinesUIFigure)
            
            if value == 0
                return
            end
            
            app.Geometry.StreamlinesFile = value;
            app.FileField.Value = app.Geometry.StreamlinesFile;
        end

        % Button pushed function: NewStreamlinesButton
        function NewStreamlinesButtonPushed(app, event)
            app.StatusLamp.Enable = 'on';
            app.StatusLampLabel.Text = 'BUSY';
            app.StatusMessageLabel.Text = 'Selecting random subset of streamlines.';
            
            drawnow

            try
                save_name = select_random_streamlines(app.Geometry, app.CallingApp.PATHS);
                app.Geometry.StreamlinesFile = save_name;
                app.FileField.Value = app.Geometry.StreamlinesFile;
            
            catch ME
                msgbox(ME.message)
                
            end
            
            app.StatusLamp.Enable = 'off';
            app.StatusLampLabel.Text = 'READY';
            app.StatusMessageLabel.Text = '';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StreamlinesUIFigure = uifigure('Visible', 'off');
            app.StreamlinesUIFigure.Name = 'Streamlines';
            app.StreamlinesUIFigure.CloseRequestFcn = createCallbackFcn(app, @StreamlinesUIFigureCloseRequest, true);
            app.StreamlinesLabel = uilabel(app.StreamlinesUIFigure);
            app.StreamlinesLabel.Text = 'Streamlines';
            app.HelpStreamlinesButton = uibutton(app.StreamlinesUIFigure, 'push');
            app.HelpStreamlinesButton.Text = '?';
            app.ApplyButton = uibutton(app.StreamlinesUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.StreamlinesUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResetButton = uibutton(app.StreamlinesUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadStreamlinesButton = uibutton(app.StreamlinesUIFigure, 'push');
            app.LoadStreamlinesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStreamlinesButtonPushed, true);
            app.LoadStreamlinesButton.Text = 'Load file';
            app.NewStreamlinesButton = uibutton(app.StreamlinesUIFigure, 'push');
            app.NewStreamlinesButton.ButtonPushedFcn = createCallbackFcn(app, @NewStreamlinesButtonPushed, true);
            app.NewStreamlinesButton.Text = {'Draw new'; 'streamlines'};
            app.StatusMessageLabel = uilabel(app.StreamlinesUIFigure);
            app.StatusMessageLabel.Text = 'Status message';
            app.StatusLampLabel = uilabel(app.StreamlinesUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.StreamlinesUIFigure);
            app.FileFieldLabel = uilabel(app.StreamlinesUIFigure);
            app.FileFieldLabel.Text = 'Streamlines file';
            app.FileField = uieditfield(app.StreamlinesUIFigure, 'text');
            app.FileField.Editable = 'off';
            % Show the figure after all components are created
            app.StreamlinesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StreamlinesGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StreamlinesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StreamlinesUIFigure)
        end
    end
end